classdef mbv_pipe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        run_mbvpipeButton             matlab.ui.control.Button
        smooth_sizeTextArea           matlab.ui.control.TextArea
        smooth_sizeTextAreaLabel      matlab.ui.control.Label
        VBManalysisLabel              matlab.ui.control.Label
        scale_factorTextArea          matlab.ui.control.TextArea
        scale_factorTextAreaLabel     matlab.ui.control.Label
        cost_funcListBox              matlab.ui.control.ListBox
        cost_funcListBoxLabel         matlab.ui.control.Label
        freedom_degreeTextArea        matlab.ui.control.TextArea
        freedom_degreeTextAreaLabel   matlab.ui.control.Label
        TPMregistrationLabel          matlab.ui.control.Label
        modified_voxel_sizeTextArea   matlab.ui.control.TextArea
        modified_voxel_sizeTextAreaLabel  matlab.ui.control.Label
        spatialresolutionadjustmentLabel  matlab.ui.control.Label
        bspline_orderTextArea         matlab.ui.control.TextArea
        bspline_orderTextAreaLabel    matlab.ui.control.Label
        shrink_factorTextArea         matlab.ui.control.TextArea
        shrink_factorTextAreaLabel    matlab.ui.control.Label
        nonuniformitycorrectionLabel  matlab.ui.control.Label
        reorientationLabel            matlab.ui.control.Label
        new_orientationTextArea       matlab.ui.control.TextArea
        new_orientationTextAreaLabel  matlab.ui.control.Label
        raw_orientationTextArea       matlab.ui.control.TextArea
        raw_orientationTextAreaLabel  matlab.ui.control.Label
        generate_cfgButton            matlab.ui.control.Button
        project_dirTextArea           matlab.ui.control.TextArea
        project_dirTextAreaLabel      matlab.ui.control.Label
        select_dirButton              matlab.ui.control.Button
        MBVpipeGUILabel               matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: select_dirButton
        function select_dirButtonPushed(app, event)
            proj_dir = uigetdir();
            app.project_dirTextArea.Value = proj_dir;
        end

        % Button pushed function: generate_cfgButton
        function generate_cfgButtonPushed(app, event)
            if iscell(app.project_dirTextArea.Value)
                cfg_fname = fullfile(app.project_dirTextArea.Value{1}, 'mbv-pipe.cfg');
            else
                cfg_fname = fullfile(app.project_dirTextArea.Value, 'mbv-pipe.cfg');
            end
            cfg_fid = fopen(cfg_fname, 'w');
            % write config file
            fprintf(cfg_fid, "%s\n", '# Project dir needed to be set manually!!!');
            fprintf(cfg_fid, "MBV_Pipe_dir='%s'\n", app.project_dirTextArea.Value{1});
            fprintf(cfg_fid, "\n");
            fprintf(cfg_fid, "%s\n", '# Parameters that depend on project dir');
            fprintf(cfg_fid, "data_dir=${MBV_Pipe_dir}/'data'\n");
            fprintf(cfg_fid, "code_dir=${MBV_Pipe_dir}/'code'\n");
            fprintf(cfg_fid, "atlasBREX_mouse_dir=${code_dir}/'atlasBREX/templates/mouse_T2w'\n");
            fprintf(cfg_fid, "TPM_raw_res_dir=${data_dir}/'DSURQE_TPM/raw_res'\n");
            fprintf(cfg_fid, "TPM_x10_dir=${data_dir}/'DSURQE_TPM/res_x10'\n");
            fprintf(cfg_fid, "ara_100um_dir=${data_dir}/'ARA/100um'\n");
            fprintf(cfg_fid, "\n");
            fprintf(cfg_fid, "dartel_tpm_dir=${data_dir}/'dartel_tpm'\n");
            fprintf(cfg_fid, "dartel_tpm_GM_fpath=${dartel_tpm_dir}/'TPM_GM_1.5mm.nii'\n");
            fprintf(cfg_fid, "dartel_tpm_WM_fpath=${dartel_tpm_dir}/'TPM_WM_1.5mm.nii'\n");
            fprintf(cfg_fid, "dartel_tpm_CSF_fpath=${dartel_tpm_dir}/'TPM_CSF_1.5mm.nii'\n");
            fprintf(cfg_fid, "\n");
            fprintf(cfg_fid, "TPM_GM_fpath=${TPM_raw_res_dir}/'TPM_GM.nii'\n");
            fprintf(cfg_fid, "TPM_WM_fpath=${TPM_raw_res_dir}/'TPM_WM.nii'\n");
            fprintf(cfg_fid, "TPM_CSF_fpath=${TPM_raw_res_dir}/'TPM_CSF.nii'\n");
            fprintf(cfg_fid, "\n");
            fprintf(cfg_fid, "TPM_GM_x10_fpath=${TPM_x10_dir}/'TPM_GM_1.5mm.nii'\n");
            fprintf(cfg_fid, "TPM_WM_x10_fpath=${TPM_x10_dir}/'TPM_WM_1.5mm.nii'\n");
            fprintf(cfg_fid, "TPM_CSF_x10_fpath=${TPM_x10_dir}/'TPM_CSF_1.5mm.nii'\n");
            fprintf(cfg_fid, "\n");
            fprintf(cfg_fid, "ara_tmpl_fpath=${ara_100um_dir}/'tmpl_100um.nii'\n");
            fprintf(cfg_fid, "ara_annt_fpath=${ara_100um_dir}/'annt_100um.nii'\n");
            fprintf(cfg_fid, "\n");
            % constant.
            fprintf(cfg_fid, "# constant.\n");
            fprintf(cfg_fid, "T2_invivo_basename='T2_invivo'\n");
            fprintf(cfg_fid, "T2_perfused_basename='T2_perfused'\n");
            fprintf(cfg_fid, "T2_fixed_basename='T2_fixed'\n");
            fprintf(cfg_fid, "nii_ext='.nii'\n");
            fprintf(cfg_fid, "nii_gz_ext='.nii.gz'\n");
            fprintf(cfg_fid, "\n");
            % --- S1. reorientation ---
            fprintf(cfg_fid, "# --- S1. reorientation ---\n");
            fprintf(cfg_fid, "in_orientation='%s'\n", app.raw_orientationTextArea.Value{1});
            fprintf(cfg_fid, "out_orientation='%s'\n", app.new_orientationTextArea.Value{1});
            fprintf(cfg_fid, "\n");
            % --- S2. N4 bias correction ---
            fprintf(cfg_fid, "# --- S2. N4 bias correction ---\n");
            fprintf(cfg_fid, "n_dim='3'\n");
            fprintf(cfg_fid, "shrink_factor='%s'\n", app.shrink_factorTextArea.Value{1});
            fprintf(cfg_fid, "bspline_order='%s'\n", app.bspline_orderTextArea.Value{1});
            fprintf(cfg_fid, "\n");
            % --- S3. skull stripping ---
            fprintf(cfg_fid, "# --- S3. skull stripping ---\n");
            fprintf(cfg_fid, "b_mouse_T2w_fpath=${atlasBREX_mouse_dir}/'b_mouse_T2w.nii.gz'\n");
            fprintf(cfg_fid, "nb_mouse_T2w_fpath=${atlasBREX_mouse_dir}/'nb_mouse_T2w.nii.gz'\n");
            fprintf(cfg_fid, "atlasBREX_sh=${atlasBREX_mouse_dir}/'atlasBREX.sh'\n");
            fprintf(cfg_fid, "perfused_threshold='0.5'\n");
            fprintf(cfg_fid, "fixed_threshold='0.5'\n");
            fprintf(cfg_fid, "\n");
            % --- S4. vox_size_modification ---
            fprintf(cfg_fid, "# --- S4. vox_size_modification ---\n");
            fprintf(cfg_fid, "new_vox_size_iso='%s'\n", app.modified_voxel_sizeTextArea.Value{1});
            fprintf(cfg_fid, "save_raw_res_files_flag='0'\n");
            fprintf(cfg_fid, "\n");
            % --- S5_1. reg2tpm ---
            fprintf(cfg_fid, "# --- S5_1. reg2tpm ---\n");
            fprintf(cfg_fid, "degree_of_freedom='%s' # ridge registration\n", app.freedom_degreeTextArea.Value{1});
            fprintf(cfg_fid, "cost_func='%s'\n", app.cost_funcListBox.Value);
            fprintf(cfg_fid, "\n");
            % --- S5_2. reg2ara ---
            fprintf(cfg_fid, "# --- S5_2. reg2ara ---\n");
            fprintf(cfg_fid, "nii_dim='3'\n");
            fprintf(cfg_fid, "num_cpu='32'\n");
            fprintf(cfg_fid, "\n");
            % --- S5_3. anntreg2sbj ---
            fprintf(cfg_fid, "# --- S5_3. anntreg2sbj ---\n");
            fprintf(cfg_fid, "interpolation_method='NearestNeighbor'\n");
            fprintf(cfg_fid, "\n");
            % --- S6_1. vbm analysis ---
            fprintf(cfg_fid, "# --- S6_1. vbm analysis ---\n");
            fprintf(cfg_fid, "scale_factor='%s'\n", app.scale_factorTextArea.Value{1});
            fprintf(cfg_fid, "\n");
            % --- S6_1_a. vbm correct ---
            fprintf(cfg_fid, "# --- S6_1_a. vbm correct ---\n");
            fprintf(cfg_fid, "mask_fpath=${TPM_x10_dir}/'DSURQE150um_x10_excluded_mask_312rois.nii'\n");
            fprintf(cfg_fid, "\n");
            % --- S6_2. roi stats ---
            fprintf(cfg_fid, "# --- S6_2. roi stats ---\n");
            fprintf(cfg_fid, "roi_acronym_fpath=${ara_100um_dir}/'ara_roi_acronym.xlsx'\n");
            fprintf(cfg_fid, "\n");
            fprintf(cfg_fid, "\n");
            % --- S9. smooth ---
            fprintf(cfg_fid, "# --- S9. smooth ---\n");
            fprintf(cfg_fid, "smooth_size='%s'\n", app.smooth_sizeTextArea.Value{1});
%             fprintf(cfg_fid, "\n");
%             fprintf(cfg_fid, "\n");
%             fprintf(cfg_fid, "\n");
%             fprintf(cfg_fid, "\n");
%             fprintf(cfg_fid, "\n");
%             fprintf(cfg_fid, "\n");


            fclose(cfg_fid);
        end

        % Button pushed function: run_mbvpipeButton
        function run_mbvpipeButtonPushed(app, event)
            if isfile('mbv-pipe.cfg') && isfile('mbv-pipe.sh')
                syetem('./mbv-pipe.sh');
            else
                fig = app.UIFigure;
                uialert(fig, 'File not found','File Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MBVpipeGUILabel = uilabel(app.UIFigure);
            app.MBVpipeGUILabel.Text = 'MBV-pipe GUI';
            app.select_dirButton = uibutton(app.UIFigure, 'push');
            app.select_dirButton.ButtonPushedFcn = createCallbackFcn(app, @select_dirButtonPushed, true);
            app.select_dirButton.Tooltip = {'select the project directory'};
            app.select_dirButton.Text = 'select_dir';
            app.project_dirTextAreaLabel = uilabel(app.UIFigure);
            app.project_dirTextAreaLabel.Text = 'project_dir';
            app.project_dirTextArea = uitextarea(app.UIFigure);
            app.project_dirTextArea.Value = {'D:\Workspace\MBV-Pipe\GUI_design'};
            app.generate_cfgButton = uibutton(app.UIFigure, 'push');
            app.generate_cfgButton.ButtonPushedFcn = createCallbackFcn(app, @generate_cfgButtonPushed, true);
            app.generate_cfgButton.Text = 'generate_cfg';
            app.raw_orientationTextAreaLabel = uilabel(app.UIFigure);
            app.raw_orientationTextAreaLabel.Text = 'raw_orientation';
            app.raw_orientationTextArea = uitextarea(app.UIFigure);
            app.raw_orientationTextArea.Tooltip = {'raw nifti image orientation'};
            app.raw_orientationTextArea.Value = {'LIA'};
            app.new_orientationTextAreaLabel = uilabel(app.UIFigure);
            app.new_orientationTextAreaLabel.Text = 'new_orientation';
            app.new_orientationTextArea = uitextarea(app.UIFigure);
            app.new_orientationTextArea.Tooltip = {'corrected nifti image orientation'; 'default: RAS'};
            app.new_orientationTextArea.Value = {'RAS'};
            app.reorientationLabel = uilabel(app.UIFigure);
            app.reorientationLabel.Text = '1. reorientation';
            app.nonuniformitycorrectionLabel = uilabel(app.UIFigure);
            app.nonuniformitycorrectionLabel.Text = '2. non-uniformity correction';
            app.shrink_factorTextAreaLabel = uilabel(app.UIFigure);
            app.shrink_factorTextAreaLabel.Text = 'shrink_factor';
            app.shrink_factorTextArea = uitextarea(app.UIFigure);
            app.shrink_factorTextArea.Tooltip = {'raw nifti image orientation'};
            app.shrink_factorTextArea.Value = {'2'};
            app.bspline_orderTextAreaLabel = uilabel(app.UIFigure);
            app.bspline_orderTextAreaLabel.Text = 'bspline_order';
            app.bspline_orderTextArea = uitextarea(app.UIFigure);
            app.bspline_orderTextArea.Tooltip = {'corrected nifti image orientation'; 'default: RAS'};
            app.bspline_orderTextArea.Value = {'3'};
            app.spatialresolutionadjustmentLabel = uilabel(app.UIFigure);
            app.spatialresolutionadjustmentLabel.Text = '3. spatial resolution adjustment';
            app.modified_voxel_sizeTextAreaLabel = uilabel(app.UIFigure);
            app.modified_voxel_sizeTextAreaLabel.Text = 'modified_voxel_size';
            app.modified_voxel_sizeTextArea = uitextarea(app.UIFigure);
            app.modified_voxel_sizeTextArea.Tooltip = {'reslice to a new isotroptic resolution (mm)'};
            app.modified_voxel_sizeTextArea.Value = {'2'};
            app.TPMregistrationLabel = uilabel(app.UIFigure);
            app.TPMregistrationLabel.Text = '4. TPM registration ';
            app.freedom_degreeTextAreaLabel = uilabel(app.UIFigure);
            app.freedom_degreeTextAreaLabel.Text = 'freedom_degree';
            app.freedom_degreeTextArea = uitextarea(app.UIFigure);
            app.freedom_degreeTextArea.Tooltip = {'reslice to a new isotroptic resolution (mm)'};
            app.freedom_degreeTextArea.Value = {'6'};
            app.cost_funcListBoxLabel = uilabel(app.UIFigure);
            app.cost_funcListBoxLabel.Text = 'cost_func';
            app.cost_funcListBox = uilistbox(app.UIFigure);
            app.cost_funcListBox.Items = {'mutualinfo', 'corratio'};
            app.cost_funcListBox.Value = 'mutualinfo';
            app.scale_factorTextAreaLabel = uilabel(app.UIFigure);
            app.scale_factorTextAreaLabel.Text = 'scale_factor';
            app.scale_factorTextArea = uitextarea(app.UIFigure);
            app.scale_factorTextArea.Tooltip = {'reslice to a new isotroptic resolution (mm)'};
            app.scale_factorTextArea.Value = {'1000'};
            app.VBManalysisLabel = uilabel(app.UIFigure);
            app.VBManalysisLabel.Text = '5. VBM analysis ';
            app.smooth_sizeTextAreaLabel = uilabel(app.UIFigure);
            app.smooth_sizeTextAreaLabel.Text = 'smooth_size';
            app.smooth_sizeTextArea = uitextarea(app.UIFigure);
            app.smooth_sizeTextArea.Tooltip = {'smooth size'};
            app.smooth_sizeTextArea.Value = {'2'};
            app.run_mbvpipeButton = uibutton(app.UIFigure, 'push');
            app.run_mbvpipeButton.ButtonPushedFcn = createCallbackFcn(app, @run_mbvpipeButtonPushed, true);
            app.run_mbvpipeButton.Text = 'run_mbv-pipe';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mbv_pipe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end